classdef analiseRp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        PorcentagemLabel                matlab.ui.control.Label
        perc                            matlab.ui.control.NumericEditField
        MdiamovelamostraEditFieldLabel  matlab.ui.control.Label
        mMovel                          matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        MinEdit                         matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MaxEdit                         matlab.ui.control.NumericEditField
        DifEditFieldLabel               matlab.ui.control.Label
        DifEdit                         matlab.ui.control.NumericEditField
        tDetecEditFieldLabel            matlab.ui.control.Label
        tDetec                          matlab.ui.control.EditField
        AmostraDropDownLabel            matlab.ui.control.Label
        Amostra                         matlab.ui.control.DropDown
        EnsaioDropDownLabel             matlab.ui.control.Label
        Ensaio                          matlab.ui.control.DropDown
        MtodoDropDownLabel              matlab.ui.control.Label
        MetodoDropDown                  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        enData % Description
        tEst
        tpor
        max
        tempo
        corr
    end
    
    methods (Access = private)
        
        function updateGraph(app)
            t = app.enData{str2double(app.Amostra.Value)}(str2double(app.Ensaio.Value)).tempo;
            c = app.enData{str2double(app.Amostra.Value)}(str2double(app.Ensaio.Value)).cRMS;
            c = movmean(c,[app.mMovel.Value-1,0]);
            plot(app.UIAxes,t,c);
            m = mean(c((t<app.MaxEdit.Value)&(t>app.MinEdit.Value)));
            xlim(app.UIAxes,[0 t(end)-1]);
            line(app.UIAxes, [0 t(end)-1], [m*(1+0.01*app.perc.Value),m*(1+0.01*app.perc.Value)],'color','black','LineStyle','--');
            line(app.UIAxes, [0 t(end)-1], [m*(1-0.01*app.perc.Value),m*(1-0.01*app.perc.Value)],'color','black','LineStyle','--');
            ylim(app.UIAxes,[m*(1-0.01*app.perc.Value),m*(1+0.01*app.perc.Value)]);
            
            app.tDetec.Value = num2str(min(t((t>1)&(c<(m*(1+0.01*app.perc.Value))))));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = zeros(4,numel(app.UITable.ColumnName)); 
            app.UITable.Data(1,1) = 4.4;
            app.UITable.Data(1:4,2) = [7.5;
                                       2.5;
                                       2.5;
                                       2.5];   
            app.UITable.Data(1:3,3) = [12.8;
                                       2.5;
                                       2.5];
            app.UITable.Data(1:4,4) = [6;
                                       2.5;
                                       2.5;
                                       2.1];
            app.UITable.Data(1:3,5) = [20;
                                       2.5;
                                       2.5];
                       
            app.MinEdit.Value = app.UITable.Data(str2double(app.Amostra.Value),(str2double(app.Ensaio.Value)));
            app.DifEdit.Value = 6;     
            app.MaxEdit.Value = app.MinEdit.Value + app.DifEdit.Value;
            app.max = app.MaxEdit.Value;
            
            a = load('dados_ensaios.mat');
            app.enData = a.EnData;
            app.updateGraph();
        end

        % Value changed function: MinEdit
        function MinEditValueChanged(app, event)
            app.DifEdit.Value = app.MaxEdit.Value - app.MinEdit.Value;
            app.updateGraph();
        end

        % Value changed function: MaxEdit
        function MaxEditValueChanged(app, event)
            add = app.MaxEdit.Value - app.max;
            app.max = app.MaxEdit.Value;
            app.DifEdit.Value = app.DifEdit.Value+add;
            app.MinEdit.Value = app.MinEdit.Value+add;
            app.updateGraph();
        end

        % Value changed function: DifEdit
        function DifEditValueChanged(app, event)
            app.MinEdit.Value = app.MaxEdit.Value - app.DifEdit.Value;
            app.updateGraph();
        end

        % Value changed function: mMovel
        function mMovelValueChanged(app, event)
            app.updateGraph();
        end

        % Value changed function: perc
        function percValueChanged(app, event)
            app.updateGraph();
        end

        % Value changed function: Amostra
        function AmostraValueChanged(app, event)
            app.Ensaio.Items = sprintfc('%d',1:length(app.enData{str2double(app.Amostra.Value)}));
            app.Ensaio.Value = {'1'};
            app.updateGraph();
        end

        % Value changed function: Ensaio
        function EnsaioValueChanged(app, event)
            app.updateGraph();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Tempo [h]')
            ylabel(app.UIAxes, 'Corrente')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Am1'; 'Am2'; 'Am3'; 'Am4'; 'Am5'};
            app.PorcentagemLabel = uilabel(app.UIFigure);
            app.PorcentagemLabel.Text = 'Porcentagem [%]';
            app.perc = uieditfield(app.UIFigure, 'numeric');
            app.perc.ValueChangedFcn = createCallbackFcn(app, @percValueChanged, true);
            app.perc.Value = 0.1;
            app.MdiamovelamostraEditFieldLabel = uilabel(app.UIFigure);
            app.MdiamovelamostraEditFieldLabel.Text = 'Média movel [amostra]';
            app.mMovel = uieditfield(app.UIFigure, 'numeric');
            app.mMovel.ValueChangedFcn = createCallbackFcn(app, @mMovelValueChanged, true);
            app.mMovel.Value = 15;
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEdit = uieditfield(app.UIFigure, 'numeric');
            app.MinEdit.ValueChangedFcn = createCallbackFcn(app, @MinEditValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEdit = uieditfield(app.UIFigure, 'numeric');
            app.MaxEdit.ValueChangedFcn = createCallbackFcn(app, @MaxEditValueChanged, true);
            app.DifEditFieldLabel = uilabel(app.UIFigure);
            app.DifEditFieldLabel.Text = 'Dif';
            app.DifEdit = uieditfield(app.UIFigure, 'numeric');
            app.DifEdit.ValueChangedFcn = createCallbackFcn(app, @DifEditValueChanged, true);
            app.tDetecEditFieldLabel = uilabel(app.UIFigure);
            app.tDetecEditFieldLabel.Text = 'tDetec';
            app.tDetec = uieditfield(app.UIFigure, 'text');
            app.tDetec.Editable = 'off';
            app.AmostraDropDownLabel = uilabel(app.UIFigure);
            app.AmostraDropDownLabel.Text = 'Amostra';
            app.Amostra = uidropdown(app.UIFigure);
            app.Amostra.Items = {'1', '2', '3', '4', '5'};
            app.Amostra.ValueChangedFcn = createCallbackFcn(app, @AmostraValueChanged, true);
            app.Amostra.Value = '1';
            app.EnsaioDropDownLabel = uilabel(app.UIFigure);
            app.EnsaioDropDownLabel.Text = 'Ensaio';
            app.Ensaio = uidropdown(app.UIFigure);
            app.Ensaio.Items = {'1'};
            app.Ensaio.ValueChangedFcn = createCallbackFcn(app, @EnsaioValueChanged, true);
            app.Ensaio.Value = '1';
            app.MtodoDropDownLabel = uilabel(app.UIFigure);
            app.MtodoDropDownLabel.Text = 'Método';
            app.MetodoDropDown = uidropdown(app.UIFigure);
            app.MetodoDropDown.Items = {'Média', 'Variância'};
            app.MetodoDropDown.Value = 'Média';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analiseRp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end