classdef visualizador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        NDropDownLabel           matlab.ui.control.Label
        N1                       matlab.ui.control.DropDown
        MDropDownLabel           matlab.ui.control.Label
        M1                       matlab.ui.control.DropDown
        DDropDownLabel           matlab.ui.control.Label
        D1                       matlab.ui.control.DropDown
        GrandezaDropDownLabel    matlab.ui.control.Label
        G1                       matlab.ui.control.DropDown
        AmostraLabel             matlab.ui.control.Label
        Am1                      matlab.ui.control.DropDown
        Ax1                      matlab.ui.control.UIAxes
        Ax2                      matlab.ui.control.UIAxes
        NDropDownLabel_2         matlab.ui.control.Label
        N2                       matlab.ui.control.DropDown
        MDropDownLabel_2         matlab.ui.control.Label
        M2                       matlab.ui.control.DropDown
        DDropDownLabel_2         matlab.ui.control.Label
        D2                       matlab.ui.control.DropDown
        GrandezaDropDownLabel_2  matlab.ui.control.Label
        G2                       matlab.ui.control.DropDown
        AmostraLabel_2           matlab.ui.control.Label
        Am2                      matlab.ui.control.DropDown
        Ax3                      matlab.ui.control.UIAxes
        NDropDownLabel_3         matlab.ui.control.Label
        N3                       matlab.ui.control.DropDown
        MDropDownLabel_3         matlab.ui.control.Label
        M3                       matlab.ui.control.DropDown
        DDropDownLabel_3         matlab.ui.control.Label
        D3                       matlab.ui.control.DropDown
        GrandezaDropDownLabel_3  matlab.ui.control.Label
        G3                       matlab.ui.control.DropDown
        AmostraLabel_3           matlab.ui.control.Label
        Am3                      matlab.ui.control.DropDown
        JanelahEditFieldLabel    matlab.ui.control.Label
        Janela                   matlab.ui.control.NumericEditField
        EnsaioLabel              matlab.ui.control.Label
        En1                      matlab.ui.control.DropDown
        EnsaioLabel_2            matlab.ui.control.Label
        En2                      matlab.ui.control.DropDown
        EnsaioLabel_3            matlab.ui.control.Label
        En3                      matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        res % Cluster results
    end
    
    methods (Access = private)
        
        function atualizaFig(app,NumbFig)
            
            switch NumbFig % Extrai os valores da interface
                case 1
                    fHandle = app.Ax1;
                    N = str2double(app.N1.Value);
                    M = str2double(app.M1.Value);
                    D = str2double(app.D1.Value);
                    Gran = app.G1.Value;
                    Am = app.Am1.Value;
                    En = app.En1.Value;
                case 2
                    fHandle = app.Ax2;
                    N = str2double(app.N2.Value);
                    M = str2double(app.M2.Value);
                    D = str2double(app.D2.Value);
                    Gran = app.G2.Value;
                    Am = app.Am2.Value;
                    En = app.En2.Value;
                case 3
                    fHandle = app.Ax3;
                    N = str2double(app.N3.Value);
                    M = str2double(app.M3.Value);
                    D = str2double(app.D3.Value);
                    Gran = app.G3.Value;
                    Am = app.Am3.Value;
                    En = app.En3.Value;
            end
            
            switch Gran % Traduz o nome da grandeza
                case 'Corrente RMS'
                    Gran = 'cRMS';
                case 'Corrente Curtose'
                    Gran = 'cKur';
                case 'Corrente Variância'
                    Gran = 'cVar';
                case 'Vib. Inf. RMS'
                    Gran = 'vibInfRMS';
                case 'Vib. Inf. Curtose'
                    Gran = 'vibInfKur';
                case 'Vib. Inf. Variância'
                    Gran = 'vibInfVar';
                case 'Vib. Sup. RMS'
                    Gran = 'vibSupRMS';
                case 'Vib. Sup Curtose'
                    Gran = 'vibSupKur';
                case 'Vib. Sup Variância'
                    Gran = 'vibSupVar';
                case 'Vazão'
                    Gran = 'vazao';
                case 'Vib. Inf. LP RMS'
                    Gran = 'vibLp_RMS';
                case 'Vib. Inf. LP Cur.'
                    Gran = 'vibLp_Kur';
                case 'Vib. Inf. BP RMS'
                    Gran = 'vibBp_RMS';
                case 'Vib. Inf. BP Cur.'
                    Gran = 'vibBp_Kur';
                case 'Vib. Inf. HP RMS'
                    Gran = 'vibHp_RMS';
                case 'Vib. Inf. HP Cur.'
                    Gran = 'vibHp_Kur';
                otherwise
                    error('Grandeza Desconhecida')
            end
            
            En = str2num(En);
            
            % Seleciona os dados desejados
            data = app.res(strcmp(app.res.Grandeza,Gran)&(strcmp(app.res.Amostra,Am))&(app.res.N==N)&(app.res.M==M)&(app.res.D==D),:);
            data = table2struct(data);
            data = orderClusters(data,app.Janela.Value);
            
            % Extrai os dados do ensaio desejado
            allClusters = sort(unique(data.Cluster));
            cluster = data.Cluster(data.Ensaio==En);
            tempo = data.Tempo(data.Ensaio==En);
            
            tmax = tempo(1)+app.Janela.Value;
            
            i = 1;
            
            while tmax<tempo(end) % Calcula a proporção dos clusters para cada janela
                x(i) = tmax;
                cTemp = cluster((tempo<tmax)&(tempo>=(tmax-app.Janela.Value)));
                for p = 1:length(allClusters)
                    temp(p) = nnz(cTemp==allClusters(p))/length(cTemp);
                end
                y(i,:) = temp;
                tmax = tmax+app.Janela.Value;
                i = i+1;
            end
            
            bar(fHandle,x,y);
            legend(fHandle,num2str(allClusters),'Location',"best")
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Não escalonados    
        
            fold = 'D:\Documentos\Amaciamento\Clusterizações\Dissertacao - All\';
            fold = dir(fold); fold = fold(3:end);
            am = regexp({fold.name},'.\d*_','match'); am = unique([am{:}]);
            
            resultsTemp = [];
            
            for k1 = 1:length(am)
                names = regexp({fold.name},strcat(am(k1),'.*'),'match');
                am{k1} = upper(am{k1}(1:(end-1))); am{k1} = [am{k1},' - NS'];
                names = [names{:}];
                for k2 = 1:length(names)
                    if ~isempty(names(k2))
                        N = regexp(names{k2},'_N.*.csv','match');
                        N = str2double(N{1}(3:(end-4)));
                        F = strcat(fold(1).folder,'\',names{k2});
                        r = importClustersV2(F,am{k1},N);
                        resultsTemp = [resultsTemp;r];
                    end
                end
            end
            
            amT = am;
            
            % Escalonados
            
%             fold = 'D:\Documentos\Amaciamento\Clusterizações\All - Scaled\';
%             fold = dir(fold); fold = fold(3:end);
%             am = regexp({fold.name},'.\d*_','match'); am = unique([am{:}]);
%             
%             
%             for k1 = 1:length(am)
%                 names = regexp({fold.name},strcat(am(k1),'.*'),'match');
%                 am{k1} = upper(am{k1}(1:(end-1))); am{k1} = [am{k1},' - S'];
%                 names = [names{:}];
%                 for k2 = 1:length(names)
%                     if ~isempty(names(k2))
%                         N = regexp(names{k2},'_N.*.csv','match');
%                         N = str2double(N{1}(3:(end-4)));
%                         F = strcat(fold(1).folder,'\',names{k2});
%                         r = importClustersV2(F,am{k1},N);
%                         resultsTemp = [resultsTemp;r];
%                     end
%                 end
%                 
%             end
%             
%             amT = [amT,am];
            
            app.Am1.Items = amT;
            app.Am2.Items = amT;
            app.Am3.Items = amT;

            app.res = resultsTemp;
            
            app.M1.Items = string(unique(app.res.M));
            app.M2.Items = string(unique(app.res.M));
            app.M3.Items = string(unique(app.res.M));
            
            app.D1.Items = string(unique(app.res.D));
            app.D2.Items = string(unique(app.res.D));
            app.D3.Items = string(unique(app.res.D));
            
            app.N1.Items = string(unique(app.res.N));
            app.N2.Items = string(unique(app.res.N));
            app.N3.Items = string(unique(app.res.N));
            
            % Atualiza os gráficos
            atualizaFig(app,1);
            atualizaFig(app,2);
            atualizaFig(app,3);
        end

        % Value changed function: N1
        function N1ValueChanged(app, event)
            if str2double(app.N1.Value) == 1
                app.D1.Items = "1";
                app.D1.Value = "1";
            else
                app.D1.Items = string(unique(app.res.D));
            end
            atualizaFig(app,1);
            
        end

        % Value changed function: M1
        function M1ValueChanged(app, event)
            atualizaFig(app,1);
        end

        % Value changed function: D1
        function D1ValueChanged(app, event)
            if str2double(app.D1.Value) == 1
                app.N1.Items = string(unique(app.res.N));
            else
                N = unique(app.res.N);
                app.N1.Items = string(N(N~=1));
            end
            atualizaFig(app,1);
        end

        % Value changed function: G1
        function G1ValueChanged(app, event)
            atualizaFig(app,1);
        end

        % Value changed function: Am1
        function Am1ValueChanged(app, event)
            atualizaFig(app,1);
        end

        % Value changed function: N2
        function N2ValueChanged(app, event)
            if str2double(app.N2.Value) == 1
                app.D2.Items = "1";
                app.D1.Value = "1";
            else
                app.D2.Items = string(unique(app.res.D));
            end
            atualizaFig(app,2);
        end

        % Value changed function: M2
        function M2ValueChanged(app, event)
            atualizaFig(app,2);
        end

        % Value changed function: D2
        function D2ValueChanged(app, event)
            if str2double(app.D2.Value) == 1
                app.N2.Items = string(unique(app.res.N));
            else
                N = unique(app.res.N);
                app.N2.Items = string(N(N~=1));
            end
            atualizaFig(app,2);
        end

        % Value changed function: G2
        function G2ValueChanged(app, event)
            atualizaFig(app,2);
        end

        % Value changed function: Am2
        function Am2ValueChanged(app, event)
            atualizaFig(app,2);
        end

        % Value changed function: N3
        function N3ValueChanged(app, event)
            if str2double(app.N3.Value) == 1
                app.D3.Items = "1";
            else
                app.D3.Items = string(unique(app.res.D));
            end
            atualizaFig(app,3);
        end

        % Value changed function: M3
        function M3ValueChanged(app, event)
            atualizaFig(app,3);
        end

        % Value changed function: D3
        function D3ValueChanged(app, event)
            if str2double(app.D3.Value) == 1
                app.N3.Items = string(unique(app.res.N));
            else
                N = unique(app.res.N);
                app.N3.Items = string(N(N~=1));
            end
            atualizaFig(app,3);
        end

        % Value changed function: G3
        function G3ValueChanged(app, event)
            atualizaFig(app,3);
        end

        % Value changed function: Am3
        function Am3ValueChanged(app, event)
            atualizaFig(app,3);
        end

        % Value changed function: Janela
        function JanelaValueChanged(app, event)
            atualizaFig(app,1);
            atualizaFig(app,2);
            atualizaFig(app,3);
        end

        % Value changed function: En1
        function En1ValueChanged(app, event)
            atualizaFig(app,1);
        end

        % Value changed function: En2
        function En2ValueChanged(app, event)
            atualizaFig(app,2);
        end

        % Value changed function: En3
        function En3ValueChanged(app, event)
            atualizaFig(app,3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NDropDownLabel = uilabel(app.UIFigure);
            app.NDropDownLabel.Text = 'N';
            app.N1 = uidropdown(app.UIFigure);
            app.N1.Items = {'3'};
            app.N1.ValueChangedFcn = createCallbackFcn(app, @N1ValueChanged, true);
            app.N1.Value = '3';
            app.MDropDownLabel = uilabel(app.UIFigure);
            app.MDropDownLabel.Text = 'M';
            app.M1 = uidropdown(app.UIFigure);
            app.M1.Items = {'5', '10', '30'};
            app.M1.ValueChangedFcn = createCallbackFcn(app, @M1ValueChanged, true);
            app.M1.Value = '5';
            app.DDropDownLabel = uilabel(app.UIFigure);
            app.DDropDownLabel.Text = 'D';
            app.D1 = uidropdown(app.UIFigure);
            app.D1.Items = {'10', '30'};
            app.D1.ValueChangedFcn = createCallbackFcn(app, @D1ValueChanged, true);
            app.D1.Value = '10';
            app.GrandezaDropDownLabel = uilabel(app.UIFigure);
            app.GrandezaDropDownLabel.Text = 'Grandeza';
            app.G1 = uidropdown(app.UIFigure);
            app.G1.Items = {'Corrente RMS', 'Corrente Curtose', 'Corrente Variância', 'Vib. Inf. RMS', 'Vib. Inf. Curtose', 'Vib. Inf. Variância', 'Vib. Sup. RMS', 'Vib. Sup Curtose', 'Vib. Sup Variância', 'Vazão', 'Vib. Inf. LP RMS', 'Vib. Inf. LP Cur.', 'Vib. Inf. BP RMS', 'Vib. Inf. BP Cur.', 'Vib. Inf. HP RMS', 'Vib. Inf. HP Cur.'};
            app.G1.ValueChangedFcn = createCallbackFcn(app, @G1ValueChanged, true);
            app.G1.Value = 'Corrente RMS';
            app.AmostraLabel = uilabel(app.UIFigure);
            app.AmostraLabel.Text = 'Amostra';
            app.Am1 = uidropdown(app.UIFigure);
            app.Am1.Items = {};
            app.Am1.ValueChangedFcn = createCallbackFcn(app, @Am1ValueChanged, true);
            app.Am1.Value = {};
            app.Ax1 = uiaxes(app.UIFigure);
            title(app.Ax1, '')
            xlabel(app.Ax1, 'Tempo [h]')
            ylabel(app.Ax1, 'Proporção do cluster')
            app.Ax2 = uiaxes(app.UIFigure);
            title(app.Ax2, '')
            xlabel(app.Ax2, 'Tempo [h]')
            ylabel(app.Ax2, 'Proporção do cluster')
            app.NDropDownLabel_2 = uilabel(app.UIFigure);
            app.NDropDownLabel_2.Text = 'N';
            app.N2 = uidropdown(app.UIFigure);
            app.N2.Items = {'3'};
            app.N2.ValueChangedFcn = createCallbackFcn(app, @N2ValueChanged, true);
            app.N2.Value = '3';
            app.MDropDownLabel_2 = uilabel(app.UIFigure);
            app.MDropDownLabel_2.Text = 'M';
            app.M2 = uidropdown(app.UIFigure);
            app.M2.Items = {'5', '10', '30'};
            app.M2.ValueChangedFcn = createCallbackFcn(app, @M2ValueChanged, true);
            app.M2.Value = '5';
            app.DDropDownLabel_2 = uilabel(app.UIFigure);
            app.DDropDownLabel_2.Text = 'D';
            app.D2 = uidropdown(app.UIFigure);
            app.D2.Items = {'10', '30'};
            app.D2.ValueChangedFcn = createCallbackFcn(app, @D2ValueChanged, true);
            app.D2.Value = '10';
            app.GrandezaDropDownLabel_2 = uilabel(app.UIFigure);
            app.GrandezaDropDownLabel_2.Text = 'Grandeza';
            app.G2 = uidropdown(app.UIFigure);
            app.G2.Items = {'Corrente RMS', 'Corrente Curtose', 'Corrente Variância', 'Vib. Inf. RMS', 'Vib. Inf. Curtose', 'Vib. Inf. Variância', 'Vib. Sup. RMS', 'Vib. Sup Curtose', 'Vib. Sup Variância', 'Vazão', 'Vib. Inf. LP RMS', 'Vib. Inf. LP Cur.', 'Vib. Inf. BP RMS', 'Vib. Inf. BP Cur.', 'Vib. Inf. HP RMS', 'Vib. Inf. HP Cur.'};
            app.G2.ValueChangedFcn = createCallbackFcn(app, @G2ValueChanged, true);
            app.G2.Value = 'Corrente RMS';
            app.AmostraLabel_2 = uilabel(app.UIFigure);
            app.AmostraLabel_2.Text = 'Amostra';
            app.Am2 = uidropdown(app.UIFigure);
            app.Am2.Items = {};
            app.Am2.ValueChangedFcn = createCallbackFcn(app, @Am2ValueChanged, true);
            app.Am2.Value = {};
            app.Ax3 = uiaxes(app.UIFigure);
            title(app.Ax3, '')
            xlabel(app.Ax3, 'Tempo [h]')
            ylabel(app.Ax3, 'Proporção do cluster')
            app.NDropDownLabel_3 = uilabel(app.UIFigure);
            app.NDropDownLabel_3.Text = 'N';
            app.N3 = uidropdown(app.UIFigure);
            app.N3.Items = {'3'};
            app.N3.ValueChangedFcn = createCallbackFcn(app, @N3ValueChanged, true);
            app.N3.Value = '3';
            app.MDropDownLabel_3 = uilabel(app.UIFigure);
            app.MDropDownLabel_3.Text = 'M';
            app.M3 = uidropdown(app.UIFigure);
            app.M3.Items = {'5', '10', '30'};
            app.M3.ValueChangedFcn = createCallbackFcn(app, @M3ValueChanged, true);
            app.M3.Value = '5';
            app.DDropDownLabel_3 = uilabel(app.UIFigure);
            app.DDropDownLabel_3.Text = 'D';
            app.D3 = uidropdown(app.UIFigure);
            app.D3.Items = {'10', '30'};
            app.D3.ValueChangedFcn = createCallbackFcn(app, @D3ValueChanged, true);
            app.D3.Value = '10';
            app.GrandezaDropDownLabel_3 = uilabel(app.UIFigure);
            app.GrandezaDropDownLabel_3.Text = 'Grandeza';
            app.G3 = uidropdown(app.UIFigure);
            app.G3.Items = {'Corrente RMS', 'Corrente Curtose', 'Corrente Variância', 'Vib. Inf. RMS', 'Vib. Inf. Curtose', 'Vib. Inf. Variância', 'Vib. Sup. RMS', 'Vib. Sup Curtose', 'Vib. Sup Variância', 'Vazão', 'Vib. Inf. LP RMS', 'Vib. Inf. LP Cur.', 'Vib. Inf. BP RMS', 'Vib. Inf. BP Cur.', 'Vib. Inf. HP RMS', 'Vib. Inf. HP Cur.'};
            app.G3.ValueChangedFcn = createCallbackFcn(app, @G3ValueChanged, true);
            app.G3.Value = 'Corrente RMS';
            app.AmostraLabel_3 = uilabel(app.UIFigure);
            app.AmostraLabel_3.Text = 'Amostra';
            app.Am3 = uidropdown(app.UIFigure);
            app.Am3.Items = {};
            app.Am3.ValueChangedFcn = createCallbackFcn(app, @Am3ValueChanged, true);
            app.Am3.Value = {};
            app.JanelahEditFieldLabel = uilabel(app.UIFigure);
            app.JanelahEditFieldLabel.Text = 'Janela [h]';
            app.Janela = uieditfield(app.UIFigure, 'numeric');
            app.Janela.Limits = [0.0167 Inf];
            app.Janela.ValueChangedFcn = createCallbackFcn(app, @JanelaValueChanged, true);
            app.Janela.Value = 1;
            app.EnsaioLabel = uilabel(app.UIFigure);
            app.EnsaioLabel.Text = 'Ensaio';
            app.En1 = uidropdown(app.UIFigure);
            app.En1.Items = {'1', '2', '3'};
            app.En1.ValueChangedFcn = createCallbackFcn(app, @En1ValueChanged, true);
            app.En1.Value = '1';
            app.EnsaioLabel_2 = uilabel(app.UIFigure);
            app.EnsaioLabel_2.Text = 'Ensaio';
            app.En2 = uidropdown(app.UIFigure);
            app.En2.Items = {'1', '2', '3'};
            app.En2.ValueChangedFcn = createCallbackFcn(app, @En2ValueChanged, true);
            app.En2.Value = '1';
            app.EnsaioLabel_3 = uilabel(app.UIFigure);
            app.EnsaioLabel_3.Text = 'Ensaio';
            app.En3 = uidropdown(app.UIFigure);
            app.En3.Items = {'1', '2', '3'};
            app.En3.ValueChangedFcn = createCallbackFcn(app, @En3ValueChanged, true);
            app.En3.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualizador

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end